classdef IRIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveResourceImpactSimulationJoshuaAngeUIFigure  matlab.ui.Figure
        Background                  matlab.ui.control.Image
        INTERACTIVERESOURCEIMPACTSIMULATIONLabel  matlab.ui.control.Label
        VersionNumber               matlab.ui.control.Label
        Logo                        matlab.ui.control.Image
        GraphicsPanel               matlab.ui.container.Panel
        Main                        matlab.ui.control.UIAxes
        LogPanel                    matlab.ui.container.Panel
        ParametersPanel             matlab.ui.container.TabGroup
        InitialConditionsTab        matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        PodInformationTab           matlab.ui.container.Tab
        EnvironmentalConditionsTab  matlab.ui.container.Tab
        ProgramDataTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            folder = pwd;
            file = 'PTHeight_CraterTest';
            load(strcat(folder,'\data\outputs\',file,'.mat'));
            
            % MAIN GRAPH
            hold (app.Panel,"on");
            grid (app.Panel,"minor");
            view (app.Panel,3);
            colormap(app.Panel,"gray")
            xlim(app.Panel,[0 1000]);
            ylim(app.Panel,[0 1000]);
            zlim(app.Panel,[0 L_range]);
            surfc(app.Panel,L_x, L_y, L_z,'EdgeAlpha',0.1,'FaceAlpha',1,'DisplayName','Surface Topology') % Mesh Plot
            
             for iit = 1:i_max
                 if isempty(b{iit}) == 0
                    %fplot3(app.Panel,b{iit}.x_i,b{iit}.y_i,b{iit}.z_i,[0 double(b{iit}.t_f_i)],'DisplayName','Trajectory','lineWidth',1,'Color','black');
                 end
                 for jit = 1:j_max
                     if isempty(s{iit,jit}) == 0
                        disp(jit);
                        %quiver3(app.Panel,s{iit,jit}.O_ij(1,1),s{iit,jit}.O_ij(1,2),s{iit,jit}.O_ij(1,3),s{iit,jit}.O_ij(2,1),s{iit,jit}.O_ij(2,2),s{iit,jit}.O_ij(2,3),'lineWidth',1,'Color','black','MaxHeadSize',0.5,'DisplayName','Traced Path Segment');
                     end
                 end
             end
             hold (app.Panel,"off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure = uifigure('Visible', 'off');
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Name = 'Interactive Resource Impact Simulation - Joshua Ange';
            app.Background = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Background.ImageSource = 'Blue_Background.png';
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel.Text = 'INTERACTIVE RESOURCE IMPACT SIMULATION';
            app.VersionNumber = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.VersionNumber.Text = '1.5.0';
            app.Logo = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Logo.ImageSource = 'IRIS_White.png';
            app.GraphicsPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Main = uiaxes(app.GraphicsPanel);
            title(app.Main, 'Title')
            xlabel(app.Main, 'X')
            ylabel(app.Main, 'Y')
            app.LogPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.ParametersPanel = uitabgroup(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.InitialConditionsTab = uitab(app.ParametersPanel);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.Button = uibutton(app.InitialConditionsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PodInformationTab = uitab(app.ParametersPanel);
            app.PodInformationTab.Title = 'Pod Information';
            app.EnvironmentalConditionsTab = uitab(app.ParametersPanel);
            app.EnvironmentalConditionsTab.Title = 'Environmental Conditions';
            app.ProgramDataTab = uitab(app.ParametersPanel);
            app.ProgramDataTab.Title = 'Program Data';
            % Show the figure after all components are created
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)
        end
    end
end